classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TakeSnapShotButton    matlab.ui.control.Button
        DeterminNumberButton  matlab.ui.control.Button
        NumberTextAreaLabel   matlab.ui.control.Label
        NumberTextArea        matlab.ui.control.TextArea
        PreviewButton         matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        GreyImageButton       matlab.ui.control.Button
        EdgeImageButton       matlab.ui.control.Button
        EnhancementButton     matlab.ui.control.Button
        SegmentationButton    matlab.ui.control.Button
        determinImg           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam = webcam(1)
        cameraimage % Description
        GrayImage % Description
        edgeImage % Description
        segImage % Description
        segmentcounter=0 % Description
        output="-1" % Description
        
     
        rmatrix % Description
        halfimage % Description
    end
    
    methods (Access = private)
        
        function result = determin(app)

            image=app.segImage;
            s=strel('disk',50);
            %hand without Fingers
            segImage=imopen(image,s);
            res=image-segImage;
            %_____morphology for resuly_____
             s2=strel('disk',10);
            res=imopen(res,s2);
            res=imbinarize(res,graythresh(res));
            [l,n]=bwlabel(res);
             app.NumberTextArea.Value=num2str(n);
        end


        function result = zerocheck(app)
        image=app.edgeImage;
         [row column]=size(image);
         sum=0;
         result=0
         for i=1:row/2
             for j=1:column
                 sum=sum+image(i,j);
             end
         end
         if sum==0
             result=1
         end
            
        end
        
        function result = summ(app)
        image=app.rmatrix;
         [row column]=size(image);
     result=0;
     for i=1:row
         for j=1:column
             result=result+image(i,j)+(i+j);
         end
     end
            
        end
        
        function results = cutimage(app)
        image=app.segImage;
        [row,column]=size(image);
        for i=1:row/2
            for j=1:column
                image(i,j)=0;
            end
        end
        app.cutimage=image
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TakeSnapShotButton
        function TakeSnapShotButtonPushed(app, event)
            closePreview(app.cam)
            app.cameraimage = snapshot(app.cam);
            clear('cam');
            %imshow()
            app.UIAxes.Visible = 'on';
            imshow(app.cameraimage,'Parent',app.UIAxes);

            
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            preview(app.cam)
        end

        % Button pushed function: GreyImageButton
        function GreyImageButtonPushed(app, event)
            if(size(app.cameraimage)~=0)
                app.segmentcounter=0;
            
            app.GrayImage=rgb2gray(app.cameraimage);
            
            app.UIAxes_2.Visible = 'on';
            imshow(app.GrayImage,'Parent',app.UIAxes_2);
            
                
            end

            
        end

        % Button pushed function: EnhancementButton
        function EnhancementButtonPushed(app, event)
            if(size(app.cameraimage)~=0)
            %_________enhancement code here__________
            app.UIAxes_2.Visible = 'on';
            app.GrayImage=uint8(medfilt2(app.GrayImage,[10 10]));
            imshow(app.GrayImage,'Parent',app.UIAxes_2);
            end
        end

        % Button pushed function: EdgeImageButton
        function EdgeImageButtonPushed(app, event)

%             ______________Edge Image Code Here___________
             if(size(app.GrayImage)~=0)
                 %take app.GrayImage and apply segmentation and edge
                 %detection
                 s2=strel('disk',4); %creating filter matrix to apply errosion or dilation4(shape,size)
                 app.edgeImage=edge(app.segImage,'Sobel');
                 imwrite(app.edgeImage,'edgeimage.jpg');


                app.UIAxes_3.Visible = 'on';

                imshow(app.edgeImage,'Parent',app.UIAxes_3); 
              %                  _______To Gray_______
                 app.edgeImage = uint8(255 * app.edgeImage);
%                  app.edgeImage=imresize(app.edgeImage,[128,128]);
                 app.edgeImage=uint8(app.edgeImage);
                 t=graythresh(app.edgeImage)
                 app.edgeImage=imbinarize(app.edgeImage,t);
            end
        end

        % Button pushed function: DeterminNumberButton
        function DeterminNumberButtonPushed(app, event)
            image=app.segImage;
            s=strel('disk',50);
            %hand without Fingers
            segImage=imopen(image,s);
            res=image-segImage;
            s2=strel('disk',10);
            res=imopen(res,s2);
            res=imbinarize(res,graythresh(res));
            [l,n]=bwlabel(res);
             app.NumberTextArea.Value=num2str(n);
          %  determin(app)
                 app.determinImg.Visible = 'on';

          imshow(res,'Parent',app.determinImg); 


        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            if app.segmentcounter==0
                thre_value=graythresh(app.GrayImage);
%              [len,wed]=size(app.GrayImage);
%              thre_value=app.GrayImage(len/2,wed/2)
%              app.NumberTextArea.Value=num2str(thre_value)  
                app.segImage = imbinarize(app.GrayImage,thre_value);
%              app.segImage=(app.GrayImage <(thre_value)) & (app.GrayImage >thre_value-50)
%                 app.segImage=app.GrayImage>thre_value
                app.segmentcounter=1;
            else
                
                s2=strel('disk',8); %creating filter matrix to apply errosion or dilation4(shape,size)
                s=strel('disk',10);
                app.segImage=imerode(app.segImage,s2)
%                 app.segImage=imopen(app.segImage,s2)
                app.segImage=imdilate(app.segImage,s)


               % app.segImage=imopen(app.segImage,s2);
    
%                 app.segImage=imclose(app.segImage,s2);
                
            end
            imwrite(app.segImage,'segIm.jpg')

            
            imshow(app.segImage,'Parent',app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TakeSnapShotButton = uibutton(app.UIFigure, 'push');
            app.TakeSnapShotButton.ButtonPushedFcn = createCallbackFcn(app, @TakeSnapShotButtonPushed, true);
            app.TakeSnapShotButton.Text = 'Take SnapShot';
            app.DeterminNumberButton = uibutton(app.UIFigure, 'push');
            app.DeterminNumberButton.ButtonPushedFcn = createCallbackFcn(app, @DeterminNumberButtonPushed, true);
            app.DeterminNumberButton.Text = 'Determin Number';
            app.NumberTextAreaLabel = uilabel(app.UIFigure);
            app.NumberTextAreaLabel.Text = 'Number';
            app.NumberTextArea = uitextarea(app.UIFigure);
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '2')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '3')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.Visible = 'off';
            app.GreyImageButton = uibutton(app.UIFigure, 'push');
            app.GreyImageButton.ButtonPushedFcn = createCallbackFcn(app, @GreyImageButtonPushed, true);
            app.GreyImageButton.Text = 'Grey Image';
            app.EdgeImageButton = uibutton(app.UIFigure, 'push');
            app.EdgeImageButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeImageButtonPushed, true);
            app.EdgeImageButton.Text = 'Edge Image';
            app.EnhancementButton = uibutton(app.UIFigure, 'push');
            app.EnhancementButton.ButtonPushedFcn = createCallbackFcn(app, @EnhancementButtonPushed, true);
            app.EnhancementButton.Text = ' Enhancement';
            app.SegmentationButton = uibutton(app.UIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            app.determinImg = uiaxes(app.UIFigure);
            title(app.determinImg, 'Objects')
            xlabel(app.determinImg, '')
            ylabel(app.determinImg, '')
            app.determinImg.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end